classdef dem_kernels_generator_final_v00 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DEMgeneratorviagaussianbellsUIFigure  matlab.ui.Figure
        AboutMenu                      matlab.ui.container.Menu
        Label                          matlab.ui.control.Label
        NewDEMButton                   matlab.ui.control.Button
        DEMofthegroundPanel            matlab.ui.container.Panel
        YleftcornerEditField           matlab.ui.control.EditField
        YleftcornerEditFieldLabel      matlab.ui.control.Label
        XleftcornerEditField           matlab.ui.control.EditField
        XleftcornerEditFieldLabel      matlab.ui.control.Label
        NcellsEditField                matlab.ui.control.EditField
        NcellsEditFieldLabel           matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.EditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        WidthmEditField                matlab.ui.control.EditField
        WidthmEditFieldLabel           matlab.ui.control.Label
        KernelsPanel                   matlab.ui.container.Panel
        HeigthmEditField_2             matlab.ui.control.NumericEditField
        HeigthmEditField_2Label        matlab.ui.control.Label
        StddeviationEditField          matlab.ui.control.NumericEditField
        StddeviationEditFieldLabel     matlab.ui.control.Label
        NumberofkernelsEditField       matlab.ui.control.EditField
        NumberofkernelsEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            results=0;
            % Generamos un valor inicial del campo de juego
            xi=str2double(app.XleftcornerEditField.Value);
            Lx=str2double(app.WidthmEditField.Value);
            nx=str2double(app.NcellsEditField.Value);
            xf=xi+Lx;
            incx=Lx/nx;
            yi=str2double(app.YleftcornerEditField.Value);
            Ly=str2double(app.HeightmEditField.Value);
            ny=str2double(app.NcellsEditField.Value);
            yf=yi+Ly;
            incy=Ly/ny;
            [X,Y] = meshgrid(xi:incx:xf,yi:incy:yf);
            Z=zeros(size(X));
            
            % Genero los Kernels
            n=str2double(app.NumberofkernelsEditField.Value);
            sigma=(app.StddeviationEditField.Value);
            H=(app.HeigthmEditField_2.Value);
            for i=1:n
                % genero una posición aleatoria del kernel
                mux=xi+rand*Lx;
                muy=yi+rand*Ly;
                r=((X-mux).^2+(Y-muy).^2).^0.5;
                % k=H*sigma*(2*pi)^0.5;
                % f=k/(sigma*pi)^0.5*exp(-0.5*(r/sigma).^2);
                f=H*exp(-0.5*(r/sigma).^2);
                Z=Z+f;
            end
            % Represento la salida
            surf(app.UIAxes,X,Y,Z); 
            axis(app.UIAxes,'equal');
            
            % Guardo la salida
            f = fopen('DEM.txt', 'wt'); % creo la funcíon que abre el archivo para escribir
            fprintf(f, 'ncols\t%s\n',num2str(nx));
            fprintf(f, 'nrows\t%s\n',num2str(nx));
            fprintf(f, 'xllcorner\t%s\n',num2str(yi));
            fprintf(f, 'yllcorner\t%s\n',num2str(xi));
            fprintf(f, 'cellsize\t%s\n',num2str(incx));
            fprintf(f, 'NODATA_value\t%s\n',num2str(-9999));
            fprintf(f, [repmat('%10.5f\t', 1, size(Z,2)) '\n'], Z');
            fclose(f); % cierro el archivo

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewDEMButton
        function NewDEMButtonPushed(app, event)
            func(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DEMgeneratorviagaussianbellsUIFigure = uifigure('Visible', 'off');
            app.DEMgeneratorviagaussianbellsUIFigure.Name = 'DEM generator via gaussian bells';
            app.DEMgeneratorviagaussianbellsUIFigure.Icon = 'Icon.png';
            app.AboutMenu = uimenu(app.DEMgeneratorviagaussianbellsUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.DEMgeneratorviagaussianbellsUIFigure);
            title(app.UIAxes, 'DEM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KernelsPanel = uipanel(app.DEMgeneratorviagaussianbellsUIFigure);
            app.KernelsPanel.Title = 'Kernels';
            app.NumberofkernelsEditFieldLabel = uilabel(app.KernelsPanel);
            app.NumberofkernelsEditFieldLabel.Text = 'Number of kernels';
            app.NumberofkernelsEditField = uieditfield(app.KernelsPanel, 'text');
            app.NumberofkernelsEditField.Tooltip = {'Number of Gaussians to add. '};
            app.NumberofkernelsEditField.Value = '20';
            app.StddeviationEditFieldLabel = uilabel(app.KernelsPanel);
            app.StddeviationEditFieldLabel.Text = 'Std deviation';
            app.StddeviationEditField = uieditfield(app.KernelsPanel, 'numeric');
            app.StddeviationEditField.Tooltip = {'Standard deviation of the bell curve (the kernel)'};
            app.StddeviationEditField.Value = 5;
            app.HeigthmEditField_2Label = uilabel(app.KernelsPanel);
            app.HeigthmEditField_2Label.Text = 'Heigth [m]';
            app.HeigthmEditField_2 = uieditfield(app.KernelsPanel, 'numeric');
            app.HeigthmEditField_2.Tooltip = {'Heigth of the bell curve. If possitive, it will create mountains. If negative, craters.'};
            app.HeigthmEditField_2.Value = 3;
            app.DEMofthegroundPanel = uipanel(app.DEMgeneratorviagaussianbellsUIFigure);
            app.DEMofthegroundPanel.Title = 'DEM of the ground';
            app.WidthmEditFieldLabel = uilabel(app.DEMofthegroundPanel);
            app.WidthmEditFieldLabel.Text = 'Width [m]';
            app.WidthmEditField = uieditfield(app.DEMofthegroundPanel, 'text');
            app.WidthmEditField.Tooltip = {'Width of the ground (X axis)'};
            app.WidthmEditField.Value = '100';
            app.HeightmEditFieldLabel = uilabel(app.DEMofthegroundPanel);
            app.HeightmEditFieldLabel.Text = 'Height [m]';
            app.HeightmEditField = uieditfield(app.DEMofthegroundPanel, 'text');
            app.HeightmEditField.Tooltip = {'Heigth of the ground (Y axis)'};
            app.HeightmEditField.Value = '100';
            app.NcellsEditFieldLabel = uilabel(app.DEMofthegroundPanel);
            app.NcellsEditFieldLabel.Text = 'N cells';
            app.NcellsEditField = uieditfield(app.DEMofthegroundPanel, 'text');
            app.NcellsEditField.Tooltip = {'Number of cells to divide the ground.'};
            app.NcellsEditField.Value = '50';
            app.XleftcornerEditFieldLabel = uilabel(app.DEMofthegroundPanel);
            app.XleftcornerEditFieldLabel.Text = 'X left corner';
            app.XleftcornerEditField = uieditfield(app.DEMofthegroundPanel, 'text');
            app.XleftcornerEditField.Tooltip = {'Coordinates of the left corner (X axis)'};
            app.XleftcornerEditField.Value = '100';
            app.YleftcornerEditFieldLabel = uilabel(app.DEMofthegroundPanel);
            app.YleftcornerEditFieldLabel.Text = 'Y left corner';
            app.YleftcornerEditField = uieditfield(app.DEMofthegroundPanel, 'text');
            app.YleftcornerEditField.Tooltip = {'Coordinates of the left corner (Y axis)'};
            app.YleftcornerEditField.Value = '200';
            app.NewDEMButton = uibutton(app.DEMgeneratorviagaussianbellsUIFigure, 'push');
            app.NewDEMButton.ButtonPushedFcn = createCallbackFcn(app, @NewDEMButtonPushed, true);
            app.NewDEMButton.Tooltip = {'Generate a random DEM with the provided values. The DEM file is saved as an ASCII file in the current folder.'};
            app.NewDEMButton.Text = 'New DEM';
            app.Label = uilabel(app.DEMgeneratorviagaussianbellsUIFigure);
            app.Label.Text = {'DEM generator by bell curves'; 'Adrián Riquelme, ariquelme@ua.es'; 'University of Alicante, January 2021'};
            % Show the figure after all components are created
            app.DEMgeneratorviagaussianbellsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dem_kernels_generator_final_v00

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DEMgeneratorviagaussianbellsUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.DEMgeneratorviagaussianbellsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DEMgeneratorviagaussianbellsUIFigure)
        end
    end
end